classdef thermo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ThermoMeter         matlab.ui.Figure
        btn_load_usrdat     matlab.ui.control.Button
        lbl_usrdat_name     matlab.ui.control.Label
        TabGroup            matlab.ui.container.TabGroup
        Tab                 matlab.ui.container.Tab
        ax_estim            matlab.ui.control.UIAxes
        btn_start_estim     matlab.ui.control.StateButton
        Tab_2               matlab.ui.container.Tab
        ax_measure          matlab.ui.control.UIAxes
        btn_load_meas_dat   matlab.ui.control.Button
        btn_save_meas_dat   matlab.ui.control.Button
        btn_ident           matlab.ui.control.Button
        btn_save_ident_mdl  matlab.ui.control.Button
        btn_start_measure   matlab.ui.control.StateButton
        btn_select_alg      matlab.ui.control.Button
        lbl_alg_name        matlab.ui.control.Label
        COMDropDownLabel    matlab.ui.control.Label
        COMDropDown         matlab.ui.control.DropDown
        btn_updt_comports   matlab.ui.control.Button
    end

    
    properties (Access = private)
        usr_mdl_ldd % Description
        sample_rate = 0.1;
        ident_time = 600;
        estim_time = 180;
        serial_baud = 115200;
        data_conv_gain = [2^-1; 2^7] * 5/1023/25;
    end
    
    methods (Access = private)
        function Th = conv_thermister(app, Vo, B, R0, Rf, T0, Vi)
            Rth = Rf*(Vi-Vo)./Vo;
            Th = 1./(1/B*log(Rth/R0)+1/T0)  - 273.15 ;
        end
        
        function measureIdentData(app)
            load("./data/thermistor_params.mat", "B", "R0", "Rf", "T0", "Vi");
            
            d_len = round(app.ident_time/app.sample_rate);
            
            t = (0:d_len-1)*app.sample_rate;
            plot(app.ax_measure, t, nan(d_len, 1));
            legend(app.ax_measure, "サーミスタ検出温度(実測)", "Location", "southeast");
            
            comport_prev = app.COMDropDown.Value;
            save("./settings.mat", "comport_prev", "-append");
            s = serialport(comport_prev, app.serial_baud);
            configureTerminator(s, 255);
            
            for d_cnt = 1:d_len
                app.ax_measure.Children(1).YData(d_cnt) = app.conv_thermister(double(char(readline(s))) * app.data_conv_gain, B, R0, Rf, T0, Vi);
                
                if (app.btn_start_measure.Value == 0)
                    break;
                end
            end
            
            app.btn_start_measure.Text = "計測開始";
            app.btn_start_measure.Value = 0;
        end
        
        function estimBodyTemp(app)            
            if (app.lbl_alg_name.Text == "N/A")
                msgbox("同定アルゴリズムを選択してください", "アルゴリズムが選択されていません", "modal");
                return;
            end
            
            if (~exist(app.lbl_alg_name.Text,"class"))
                msgbox("同定アルゴリズム" + app.lbl_alg_name.Text + "が見つかりません", "アルゴリズムが見つかりません", "modal");
                return;
            end
            
            if (isempty(app.usr_mdl_ldd))
                msgbox("ユーザモデルを読み込んでください", "ユーザモデルが読み込まれていません", "modal");
                return;
            end
            
            obj_alg = feval(app.lbl_alg_name.Text);
            
            if (~ismethod(obj_alg, "estim"))
                msgbox("同定アルゴリズム" + app.lbl_alg_name.Text + "にestimメソッドが定義されていません", "推定用メソッドが見つかりません", "modal");
                return;
            end

            if (ismethod(obj_alg, "init"))
                obj_alg.init(app.usr_mdl_ldd);
            end
            
            load("./data/thermistor_params.mat", "B", "R0", "Rf", "T0", "Vi");            
            
            d_len = round(app.estim_time/app.sample_rate);
            t = (0:d_len-1)*app.sample_rate;
            
            app.ax_estim.ColorOrderIndex = 1;
            plot(app.ax_estim, t, nan(d_len, 1), t, nan(d_len, 1));
            legend(app.ax_estim, "サーミスタ検出温度(実測)", "体温(推定)");
            app.ax_estim.YLim = [20, 50];
            
            comport_prev = app.COMDropDown.Value;
            save("./settings.mat", "comport_prev", "-append");
            s = serialport(comport_prev, app.serial_baud);
            configureTerminator(s, 255);
            
            for d_cnt = 1:d_len
                temp_meas = app.conv_thermister(double(char(readline(s))) * app.data_conv_gain, B, R0, Rf, T0, Vi);

                temp_est = obj_alg.estim(temp_meas);
                
                app.ax_estim.Children(2).YData(d_cnt) = temp_meas;
                app.ax_estim.Children(1).YData(d_cnt) = temp_est;
                
                app.ax_estim.Title.String = "推定体温 = " + num2str(temp_est, 3) + "℃";
                
                if (app.btn_start_estim.Value == 0)
                    break;
                end
            end
            
            app.btn_start_estim.Text = "体温予測開始";
            app.btn_start_estim.Value = 0;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath("./algorithm/");
            
            app.usr_mdl_ldd = [];
            app.COMDropDown.Items = serialportlist;
            
            alg_name_prev = "";
            usrdat_name_prev = "";
            comport_prev = "";
            
            if (~exist("./settings.mat", "file"))
                save("./settings.mat", "alg_name_prev", "usrdat_name_prev", "comport_prev");
                
                app.lbl_alg_name.Text = "N/A";
                app.lbl_usrdat_name.Text = "N/A";
            else
                load("./settings.mat", "alg_name_prev", "usrdat_name_prev", "comport_prev");
                
                if (exist(alg_name_prev, "class"))
                    app.lbl_alg_name.Text = alg_name_prev;
                else
                    app.lbl_alg_name.Text = "N/A";
                end
                
                if (exist("./algorithm/@" + alg_name_prev + "/usr_mdl/" + usrdat_name_prev + ".mat", "file") || app.lbl_alg_name == "N/A")
                    load("./algorithm/@" + alg_name_prev + "/usr_mdl/" + usrdat_name_prev + ".mat", "usr_mdl");
                    app.usr_mdl_ldd = usr_mdl;
                    app.lbl_usrdat_name.Text = usrdat_name_prev;
                else
                    app.lbl_usrdat_name.Text = "N/A";
                end
                
                if ( any(contains(app.COMDropDown.Items, comport_prev)) )
                    app.COMDropDown.Value = comport_prev;
                end
            end
        end

        % Value changed function: btn_start_measure
        function btn_start_measureValueChanged(app, event)
            value = app.btn_start_measure.Value;
            switch (value)
                case 0
                    app.btn_start_measure.Text = "計測開始";
                case 1
                    app.btn_start_measure.Text = "計測終了";
                    app.measureIdentData();
            end
        end

        % Button pushed function: btn_updt_comports
        function btn_updt_comportsButtonPushed(app, event)
            app.COMDropDown.Items = serialportlist;
        end

        % Button pushed function: btn_load_meas_dat
        function btn_load_meas_datButtonPushed(app, event)
            [file, path] = uigetfile("*.mat", "計測データの読み込み", "./data/measured_data/measured_data.mat", "MultiSelect","off");
            
            if ( file ~= 0 )
                load([path, file], "measured_data");
                
                t = (0:length(measured_data)-1)*app.sample_rate;
                plot(app.ax_measure, t, measured_data);
                legend(app.ax_measure, "サーミスタ検出温度(実測)", "Location", "southeast");
            end
        end

        % Button pushed function: btn_save_meas_dat
        function btn_save_meas_datButtonPushed(app, event)
            measured_data = rmmissing(app.ax_measure.Children(1).YData);
            
            if (isempty(measured_data))
                msgbox("温度計測を実行してください", "計測データがありません", "modal");
                return;
            end
            
            [file, path] = uiputfile("*.mat", "計測データの保存", "./data/measured_data/measured_data.mat");
            
            if (file ~= 0)
                save([path, file], "measured_data");
            end
        end

        % Button pushed function: btn_ident
        function btn_identButtonPushed(app, event)
            if (app.lbl_alg_name.Text == "N/A")
                msgbox("同定アルゴリズムを選択してください", "アルゴリズムが選択されていません", "modal");
                return;
            end
            
            if (~exist(app.lbl_alg_name.Text,"class"))
                msgbox("同定アルゴリズム" + app.lbl_alg_name.Text + "が見つかりません", "アルゴリズムが見つかりません", "modal");
                return;
            end
            
            obj_alg = feval(app.lbl_alg_name.Text);
            
            if (~ismethod(obj_alg, "ident"))
                msgbox("同定アルゴリズム" + app.lbl_alg_name.Text + "にidentメソッドが定義されていません", "同定用メソッドが見つかりません", "modal");
                return;
            end
            
            measured_data = rmmissing(app.ax_measure.Children(1).YData);
            
            [app.usr_mdl_ldd, mdl_estim] = obj_alg.ident(measured_data);
            
            t = (0:length(mdl_estim)-1) * 0.1;
            plot(app.ax_measure, t, measured_data, "LineWidth", 3, "Color", 0.6*[1 1 1]);
            hold(app.ax_measure, "on");
            plot(app.ax_measure, t, mdl_estim);
            hold(app.ax_measure, "off");
            legend(app.ax_measure, "サーミスタ検出温度(実測)", "サーミスタ検出温度(モデル応答)", "Location", "southeast");
            app.lbl_usrdat_name.Text = "Untitled";
        end

        % Button pushed function: btn_save_ident_mdl
        function btn_save_ident_mdlButtonPushed(app, event)
            if (app.lbl_usrdat_name.Text == "N/A")
                deffilename = "./algorithm/@" + app.lbl_alg_name.Text;
            else
                deffilename = "./algorithm/";
            end
            [file, path] = uiputfile("*.mat", "ユーザモデルの保存", deffilename);
            
            if ( file ~= 0 )
                usr_mdl = app.usr_mdl_ldd;
                save([path, file], "usr_mdl");
                
                [~,file,~] = fileparts(file);
                app.lbl_usrdat_name.Text = file;
            end
        end

        % Button pushed function: btn_select_alg
        function btn_select_algButtonPushed(app, event)
            file = uigetfile("*.m", "アルゴリズムを選択してください", "./algorithm/");
            
            if (file ~= 0)
                [~,file,~] = fileparts(file);
                app.lbl_alg_name.Text = file;
                
                alg_name_prev = file;
                save("./settings.mat", "alg_name_prev", "-append");
            end
        end

        % Button pushed function: btn_load_usrdat
        function btn_load_usrdatButtonPushed(app, event)
            if (app.lbl_usrdat_name.Text == "N/A")
                deffilename = "./algorithm/@" + app.lbl_alg_name.Text;
            else
                deffilename = "./algorithm/";
            end
            [file, path] = uigetfile("*.mat", "ユーザモデルの読み込み", deffilename, "MultiSelect","off");
            
            if ( file ~= 0 )
                load([path, file], "usr_mdl");
                app.usr_mdl_ldd = usr_mdl;
                
                [~,file,~] = fileparts(file);
                app.lbl_usrdat_name.Text = file;
                
                usrdat_name_prev = file;
                save("./settings.mat", "usrdat_name_prev", "-append");
            end
        end

        % Value changed function: btn_start_estim
        function btn_start_estimValueChanged(app, event)
            value = app.btn_start_estim.Value;
            switch (value)
                case 0
                    app.btn_start_estim.Text = "体温予測開始";
                case 1
                    app.btn_start_estim.Text = "体温予測終了";
                    app.estimBodyTemp();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ThermoMeter = uifigure('Visible', 'off');
            app.ThermoMeter.Name = 'MATLAB温度計モドキ';
            app.btn_load_usrdat = uibutton(app.ThermoMeter, 'push');
            app.btn_load_usrdat.ButtonPushedFcn = createCallbackFcn(app, @btn_load_usrdatButtonPushed, true);
            app.btn_load_usrdat.Text = 'ユーザモデル読み込み';
            app.lbl_usrdat_name = uilabel(app.ThermoMeter);
            app.lbl_usrdat_name.Text = 'N/A';
            app.TabGroup = uitabgroup(app.ThermoMeter);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '体温測定';
            app.ax_estim = uiaxes(app.Tab);
            title(app.ax_estim, '推定体温 = ')
            xlabel(app.ax_estim, '時間 [s]')
            ylabel(app.ax_estim, '温度 [\circC]')
            app.ax_estim.XLim = [0 180];
            app.btn_start_estim = uibutton(app.Tab, 'state');
            app.btn_start_estim.ValueChangedFcn = createCallbackFcn(app, @btn_start_estimValueChanged, true);
            app.btn_start_estim.Text = '体温予測開始';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = 'モデル同定';
            app.ax_measure = uiaxes(app.Tab_2);
            title(app.ax_measure, '')
            xlabel(app.ax_measure, '時間 [s]')
            ylabel(app.ax_measure, '温度 [\circC]')
            app.ax_measure.XLim = [0 900];
            app.btn_load_meas_dat = uibutton(app.Tab_2, 'push');
            app.btn_load_meas_dat.ButtonPushedFcn = createCallbackFcn(app, @btn_load_meas_datButtonPushed, true);
            app.btn_load_meas_dat.Text = 'データ読み込み';
            app.btn_save_meas_dat = uibutton(app.Tab_2, 'push');
            app.btn_save_meas_dat.ButtonPushedFcn = createCallbackFcn(app, @btn_save_meas_datButtonPushed, true);
            app.btn_save_meas_dat.Text = 'データ保存';
            app.btn_ident = uibutton(app.Tab_2, 'push');
            app.btn_ident.ButtonPushedFcn = createCallbackFcn(app, @btn_identButtonPushed, true);
            app.btn_ident.Text = '同定実行';
            app.btn_save_ident_mdl = uibutton(app.Tab_2, 'push');
            app.btn_save_ident_mdl.ButtonPushedFcn = createCallbackFcn(app, @btn_save_ident_mdlButtonPushed, true);
            app.btn_save_ident_mdl.Text = 'モデル保存';
            app.btn_start_measure = uibutton(app.Tab_2, 'state');
            app.btn_start_measure.ValueChangedFcn = createCallbackFcn(app, @btn_start_measureValueChanged, true);
            app.btn_start_measure.Text = '計測開始';
            app.btn_select_alg = uibutton(app.ThermoMeter, 'push');
            app.btn_select_alg.ButtonPushedFcn = createCallbackFcn(app, @btn_select_algButtonPushed, true);
            app.btn_select_alg.Text = 'アルゴリズム選択';
            app.lbl_alg_name = uilabel(app.ThermoMeter);
            app.lbl_alg_name.Text = 'N/A';
            app.COMDropDownLabel = uilabel(app.ThermoMeter);
            app.COMDropDownLabel.Text = 'COMポート';
            app.COMDropDown = uidropdown(app.ThermoMeter);
            app.btn_updt_comports = uibutton(app.ThermoMeter, 'push');
            app.btn_updt_comports.ButtonPushedFcn = createCallbackFcn(app, @btn_updt_comportsButtonPushed, true);
            app.btn_updt_comports.Text = 'ポートリスト更新';
            % Show the figure after all components are created
            app.ThermoMeter.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = thermo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ThermoMeter)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ThermoMeter)
        end
    end
end